classdef GUI_Quantification < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        StartFrameEditField             matlab.ui.control.NumericEditField
        StartFrameEditFieldLabel        matlab.ui.control.Label
        AllButton                       matlab.ui.control.Button
        ReadyLamp                       matlab.ui.control.Lamp
        ReadyLampLabel                  matlab.ui.control.Label
        FliesListBox                    matlab.ui.control.ListBox
        FliesListBoxLabel               matlab.ui.control.Label
        ExperimentsListBox              matlab.ui.control.ListBox
        ExperimentsListBoxLabel         matlab.ui.control.Label
        ReadCSVButton                   matlab.ui.control.Button
        RightPanel                      matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        ArenaPlotTab                    matlab.ui.container.Tab
        FrameTextArea                   matlab.ui.control.TextArea
        FrameTextAreaLabel              matlab.ui.control.Label
        TimeTextArea                    matlab.ui.control.TextArea
        TimeTextAreaLabel               matlab.ui.control.Label
        LabelsButton                    matlab.ui.control.Button
        PlayButton                      matlab.ui.control.Button
        FrameSlider                     matlab.ui.control.Slider
        FrameSliderLabel                matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        PITab                           matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        GraphTab                        matlab.ui.container.Tab
        UIAxes2                         matlab.ui.control.UIAxes
        Table                           matlab.ui.container.Tab
        CopyButton_2                    matlab.ui.control.Button
        UITable2                        matlab.ui.control.Table
        PioverTimeButton                matlab.ui.control.Button
        TimeIntervalsinthetwo30secondlightphasesLabel  matlab.ui.control.Label
        EndofIntervalEditField          matlab.ui.control.NumericEditField
        EndofIntervalEditFieldLabel     matlab.ui.control.Label
        StartIntervalEditField          matlab.ui.control.NumericEditField
        StartIntervalEditFieldLabel     matlab.ui.control.Label
        PIResultsTextArea               matlab.ui.control.TextArea
        PIResultsTextAreaLabel          matlab.ui.control.Label
        PIButton                        matlab.ui.control.Button
        DistanceTab                     matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        ByIntervalTab                   matlab.ui.container.Tab
        CalculateDistanceButton         matlab.ui.control.Button
        EndDistanceIntervalEditFieldLabel  matlab.ui.control.Label
        EndDistanceIntervalEditField    matlab.ui.control.NumericEditField
        StartDistanceIntervalEditFieldLabel  matlab.ui.control.Label
        StartDistanceIntervalEditField  matlab.ui.control.NumericEditField
        AbsoluteTimeIntervalsofthewhole120secondVideoLabel  matlab.ui.control.Label
        CopyButton                      matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        ByQuadrantTab                   matlab.ui.container.Tab
        CopyButton_3                    matlab.ui.control.Button
        CalculateButton                 matlab.ui.control.Button
        UITable3                        matlab.ui.control.Table
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

% This GUI was written by Hannes Euler.
    
    methods (Access = private)
        function [xout,yout] = AllFrames(app,data)
            global xout yout
%             if ~exist('selection','var')
%                 selection = 1:length(data);
%             end
            xout = NaN([3001 length(data)]);
            yout = NaN([3001 length(data)]);
            %start = app.StartFrameEditField.Value;
            
            %start = app.StartOfProtocol(data);
            %disp(start);
            for z= 1:length(data)
                start = data(z).raw.frame(1);
                ending = data(z).raw.frame(end);
                X_array = data(z).raw.X_cm_;%(start:start+3000);
                Y_array = data(z).raw.Y_cm_;%(start:start+3000);
%                 X_array = X_array-14.3;
%                 Y_array = (Y_array-15.3)*-1;
        
                X_array = X_array-16.75;
                Y_array = (Y_array-11.8)*-1;
        
                xout(start+1:ending+1,z) = X_array;
                yout(start+1:ending+1,z) = Y_array;


            end

            plot(app.UIAxes,xout,yout);
            hold(app.UIAxes,'on')

            xline(app.UIAxes,0);
            yline(app.UIAxes,0);

            hold(app.UIAxes,'off')
            end
        
        function fileNamePre = ExperimentNameShow(~,file)
            [~,name,~] = fileparts(file);
            startSpeciName = regexp(name,'_fly');
            %speciName = name(startSpeciName+1:end);
            fileNamePre = name(1:startSpeciName-1);
            
        end
        
        function fileNamePre = ExperimentNameHide(~,file)
            [~,name,~] = fileparts(file);
            startSpeciName = regexp(name,'_fly');
            %speciName = name(startSpeciName+1:end);
            fileNamePre = name(1:startSpeciName);
            
        end        
        
        function PlotHelper(app,path,fileNamePre)
            global X Y
            global data
            global d
            disp("Start Helper");
            d=dir(append(path,fileNamePre,'*.csv'));   % dir struct of all pertinent .csv file
            n=length(d);        % how many there were
            data = struct;
            warning('off','MATLAB:table:ModifiedAndSavedVarnames');
            for i=1:n
                data(i).raw = readtable(append(path,d(i).name));  % read each file
            end
            warning('on','MATLAB:table:ModifiedAndSavedVarnames');
            app.UIAxes.Title.String = fileNamePre;
            disp("Start AllFrames");
            [X,Y] = AllFrames(app,data);
            X(isinf(X)) = nan;
            Y(isinf(Y)) = nan;
            assignin('base','X',X); %copy variables to Workspace for testing
            assignin('base','Y',Y);
            disp("Stop helper");
        end
        
        function start = StartOfProtocol(~,data)
            N = zeros(size(data(1).raw,1),1);
            for z = 1:size(data(1).raw,1)
                    N(z) = NIndi(data,z);
            end
            k = find(N<1,1);
            %start = k - 3000; solange Ende verbuggt


        end
        
        function results = PI(app)
            global X Y
            clear frame frame1 frame2
            %global results 
            
            %start = 
            %Erste Phase
            measureStartTime = app.StartIntervalEditField.Value;
            measureStopTime = app.EndofIntervalEditField.Value;
            startFrame = ((measureStartTime+30)*25)+1;        
            stopFrame = ((measureStopTime+30)*25);          
            %interval = stopFrame-startFrame;
            z=0;
            for f = startFrame:stopFrame
                z = z+1;
            
                for x = 1:width(X)
                    frameL = 0;
                    frameD = 0;
                    if (X(f,x) < 0 && Y(f,x) < 0) || (X(f,x) > 0 && Y(f,x) > 0)
                        frameL = 1;
                    end
                    if (X(f,x) < 0 && Y(f,x) > 0) || (X(f,x) > 0 && Y(f,x) < 0)
                        frameD = 1;
                    end
                    frameL1(z,x) = frameL;
                    frameD1(z,x) = frameD;
                end
            end
            %Zweite Phase
            startFrame = ((measureStartTime+90)*25)+1;
            stopFrame = ((measureStopTime+90)*25); 
            z=0;
            for f = startFrame:stopFrame
                z = z+1;
                for x = 1:width(X)
                    frameL = 0;
                    frameD = 0;
                    if (X(f,x) < 0 && Y(f,x) > 0) || (X(f,x) > 0 && Y(f,x) < 0)
                        frameL = 1;
                    end
                    if (X(f,x) < 0 && Y(f,x) < 0) || (X(f,x) > 0 && Y(f,x) > 0)
                        frameD = 1;
                    end                    
                    frameL2(z,x) = frameL;
                    frameD2(z,x) = frameD;
                end
            end
            frameAllL = cat(1,frameL1,frameL2);
            frameAllD = cat(1,frameD1,frameD2);
            numL = sum(frameAllL,2);
            numD = sum(frameAllD,2);
            lF = mean(numL);
            dF = mean(numD);
            results = (lF - dF) / (lF+dF);
        end
        
        function arraystring = num2clip(app,array)
%           NUM2CLIP copies a numerical-array to the clipboard
%   
%           ARRAYSTRING = NUM2CLIP(ARRAY)
%   
%           Copies the numerical array ARRAY to the clipboard as a tab-separated
%           string.  This format is suitable for direct pasting to Excel and other
%           programs.
%   
%           The tab-separated result is returned as ARRAYSTRING.  This
%           functionality has been included for completeness.
%   
%           Author: Grigor Browning
%           Last update: 02-Sept-2005
%           convert the numerical array to a string array
%           note that num2str pads the output array with space characters to account
%           for differing numbers of digits in each index entry
            arraystring = num2str(array); 
            arraystring(:,end+1) = char(10); %add a carrige return to the end of each row
%           reshape the array to a single line
%           note that the reshape function reshape is column based so to reshape by
%           rows one must use the inverse of the matrix
            arraystring = reshape(arraystring',1,prod(size(arraystring))); %reshape the array to a single line
            arraystringshift = [' ',arraystring]; %create a copy of arraystring shifted right by one space character
            arraystring = [arraystring,' ']; %add a space to the end of arraystring to make it the same length as arraystringshift
%           now remove the additional space charaters - keeping a single space
%           charater after each 'numerical' entry
            arraystring = arraystring((double(arraystring)~=32 | double(arraystringshift)~=32) & ~(double(arraystringshift==10) & double(arraystring)==32) );
            arraystring(double(arraystring)==32) = char(9); %convert the space characters to tab characters
            clipboard('copy',arraystring); %copy the result to the clipboard ready for pasting
        end
        end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ReadCSVButton
        function ReadCSVButtonPushed(app, event)
            clear data
            clear d
            global path
            app.ReadyLamp.Color = 'red';
            
            [~,path] = uigetfile('*.csv');
            folderContent = dir(path);
            preNames = {folderContent.name};
            namesShow = cellfun(@app.ExperimentNameShow,preNames,'UniformOutput',false);
            namesHide = cellfun(@app.ExperimentNameHide,preNames,'UniformOutput',false);
            app.ExperimentsListBox.Items = unique(namesShow(3:end));
            app.ExperimentsListBox.ItemsData = unique(namesHide(3:end));
            app.ReadyLamp.Color = 'green'; 
            %app.PIResultsTextArea.Value = "Test \b Bla";
            
        end

        % Value changed function: ExperimentsListBox
        function ExperimentsListBoxValueChanged(app, event)
            value = app.ExperimentsListBox.Value;
            app.ReadyLamp.Color = 'red';
            pause(0.5)
            global data  
            global path
            app.PlotHelper(path,value);
            app.FliesListBox.Items = cellstr(string(1:size(data,2)));
            app.FliesListBox.Value = app.FliesListBox.Items;
            app.ReadyLamp.Color = 'green';            
        end

        % Value changing function: FrameSlider
        function FrameSliderValueChanging(app, event)
            global X;
            global Y;

            
            app.UIAxes.PositionConstraint = 'innerposition';

            changingValue = event.Value;
            frames = size(X,1);
            %disp (frames);
            
            FramePos = round(frames / 100 * changingValue);
            app.TimeTextArea.Value = num2str(FramePos/25);
            app.FrameTextArea.Value = num2str(FramePos);
            valueString = app.FliesListBox.Value;
            value = cellfun(@str2num,valueString);
            
            if changingValue < 1 || FramePos > frames-25
                plot(app.UIAxes,X(:,value),Y(:,value));
                hold(app.UIAxes,'on')
                xline(app.UIAxes,0);
                yline(app.UIAxes,0);
                hold(app.UIAxes,'off')
            else
               
                plot(app.UIAxes,X(FramePos:FramePos+25,value),Y(FramePos:FramePos+25,value));
                hold(app.UIAxes,'on') 
                scatter(app.UIAxes,X(FramePos+25,value),Y(FramePos+25,value));
                
                
                %hold(app.UIAxes,'on')   
                xline(app.UIAxes,0);
                yline(app.UIAxes,0);                
                hold(app.UIAxes,'off')
            end
            hold(app.UIAxes,'on')  
            if FramePos > 750 && FramePos < 1501
                
                oR = quiver(app.UIAxes,9,9,-1.5,-1.5,'LineWidth',2,'Color','black');
                uL = quiver(app.UIAxes,-9,-9,1.5,1.5,'LineWidth',2,'Color','black');

            end
            if FramePos > 2250 && FramePos < frames-100
                oL = quiver(app.UIAxes,-9,9,1.5,-1.5,'LineWidth',2,'Color','black');
                uR = quiver(app.UIAxes,9,-9,-1.5,1.5,'LineWidth',2,'Color','black');

            end
            hold(app.UIAxes,'off')
    
        end

        % Value changed function: FliesListBox
        function FliesListBoxValueChanged(app, event)
            app.ReadyLamp.Color = 'red';
            global X Y 
            value = app.FliesListBox.Value;
            value = cellfun(@str2num,value);
            plot(app.UIAxes,X(:,value),Y(:,value));
            xline(app.UIAxes,0);
            yline(app.UIAxes,0);
            app.ReadyLamp.Color = 'green';
        end

        % Button pushed function: AllButton
        function AllButtonPushed(app, event)
            app.FliesListBox.Value = app.FliesListBox.Items;
            FliesListBoxValueChanged(app, event);
        end

        % Button pushed function: PIButton
        function PIButtonPushed(app, event)
            results = PI(app);
            app.PIResultsTextArea.Value = "PI: "+results;
        end

        % Button pushed function: PlayButton
        function Play(app, event)
            Slider = app.FrameSlider.Value;
            global i
            i = 100*25;
            for i = (Slider*25):10:(100*25)
                app.FrameSlider.Value = (i/25);
                myEvent.Origin = "Test";
                myEvent.Value = (i/25);
                FrameSliderValueChanging(app,myEvent);
                pause(0.02);
            end
        end

        % Callback function
        function LabelSwitchValueChanged(app, event)
            
        end

        % Button pushed function: LabelsButton
        function LabelsButtonPushed(app, event)
            global X Y
                frames = size(X,1);
                valueString = app.FliesListBox.Value;
                value = cellfun(@str2num,valueString);
                FramePos = round(frames / 100 * app.FrameSlider.Value);
                hold(app.UIAxes,'on')   
                txt = text(app.UIAxes,X(FramePos+25,value),Y(FramePos+25,value),valueString);
                hold(app.UIAxes,'off')

        end

        % Button pushed function: CalculateDistanceButton
        function CalculateDistanceButtonPushed(app, event)
                global X Y  
                global output
                valueString = app.FliesListBox.Value;
                value = cellfun(@str2num,valueString);

                set(app.UITable,'Data',[]);
                output = [];
                counter = 0;
                for z = value
                    counter = counter+1;
                    distance= 0;
                    for y = app.StartDistanceIntervalEditField.Value*25:25:(app.EndDistanceIntervalEditField.Value-1)*25
                        xint1 = X(y-24:y,z);
                        yint1 = Y(y-24:y,z);
                        xint2 = X(y+1:y+25,z);
                        yint2 = Y(y+1:y+25,z);
                        xint1 = mean(xint1,'omitnan');
                        yint1 = mean(yint1,'omitnan');
                        xint2 = mean(xint2,'omitnan');
                        yint2 = mean(yint2,'omitnan');
                        distInt = [xint1 yint1;xint2 yint2];
                        dist = pdist(distInt);
                        if ismissing(dist)
                            dist = 0;
                        end
                        
                        
                        distance = distance + dist;
                        

                    end
                output(counter,1) = z;
                output(counter,2) = distance;
                     
                end
                meandist = mean(output(:,2));
                output(counter+1,1) = "mean";
                output(counter+1,2) = meandist;
                set(app.UITable,'Data',output);
        end

        % Button pushed function: CopyButton
        function CopyButtonPushed(app, event)
            global output
            app.num2clip(output);
        end

        % Button pushed function: PioverTimeButton
        function PioverTimeButtonPushed(app, event)
            global X Y
            global tableData 
            clear frame frame1
            %Erste Phase
%             measureStartTime = app.StartIntervalEditField.Value;
%             measureStopTime = app.EndofIntervalEditField.Value;
            z2 = 0;
            for s = 1:120
                z2 = z2+1;
                startFrame = ((s-1)*25)+1;        
                stopFrame = (s*25);        
                %interval = stopFrame-startFrame;
                z=0;
                for f = startFrame:stopFrame
                    z = z+1;
                
                    for x = 1:width(X)
                        frameL = 0;
                        frameD = 0;
                        if (X(f,x) > 0 && Y(f,x) > 0) || (X(f,x) < 0 && Y(f,x) < 0)         
                            frameL = 1;
                        end
                        if (X(f,x) > 0 && Y(f,x) < 0) || (X(f,x) < 0 && Y(f,x) > 0)         
                            frameD = 1;
                        end
                        frameL1(z,x) = frameL;
                        frameD1(z,x) = frameD;
                    end
                end


                numL = sum(frameL1,2);
                numD = sum(frameD1,2);
                lF = mean(numL);
                dF = mean(numD);
                
                results(s) = (lF - dF) / (lF+dF);
                tableData(s,1) = z2;
                tableData(s,2) = results(s);
             end
            plot(app.UIAxes2,results);
            xline(app.UIAxes2,30)
            xline(app.UIAxes2,60)
            xline(app.UIAxes2,90)
            set(app.UITable2,'Data',tableData);
        
        end

        % Button pushed function: CopyButton_2
        function CopyButton_2Pushed(app, event)
            global tableData
            app.num2clip(tableData);
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            global distanceTableData
            global Qcounter
            global X Y
                valueString = app.FliesListBox.Value;
                value = cellfun(@str2num,valueString);
                counter = 0;
                Qcounter = zeros(120,4);
                distanceTableData = zeros(120,4);
                for z = value
                    counter = counter+1;

                    for y = 25:25:2975
                        xint1 = X(y-24:y,z);
                        yint1 = Y(y-24:y,z);
                        xint2 = X(y+1:y+25,z);
                        yint2 = Y(y+1:y+25,z);
                        xint1 = mean(xint1,'omitnan');
                        yint1 = mean(yint1,'omitnan');
                        xint2 = mean(xint2,'omitnan');
                        yint2 = mean(yint2,'omitnan');
                        distInt = [xint1 yint1;xint2 yint2];
                        dist = pdist(distInt);
                        if ismissing(dist)
                            dist = 0;
                        end
%                         disp("y: "+ y+"  z: "+z);
                        if xint1 < 0 && yint1 > 0
                            distanceTableData((y/25),1) = distanceTableData((y/25),1)+ dist;
                            Qcounter((y/25),1) = Qcounter((y/25),1) +1;
                            
                        end 
                        if xint1 > 0 && yint1 > 0
                            distanceTableData((y/25),2) = distanceTableData((y/25),2)+ dist;
                            Qcounter((y/25),2) = Qcounter((y/25),2) +1;
                        end                         
                        if xint1 < 0 && yint1 < 0
                            distanceTableData((y/25),3) = distanceTableData((y/25),3)+ dist;
                            Qcounter((y/25),3) = Qcounter((y/25),3) +1;
                        end                         
                        if xint1 > 0 && yint1 < 0
                            distanceTableData((y/25),4) = distanceTableData((y/25),4)+ dist;
                            Qcounter((y/25),4) = Qcounter((y/25),4) +1;
                        end
                    end  
                end
                for h = 1:120
                    distanceTableData(h,1) = distanceTableData(h,1)/Qcounter(h,1);
                    distanceTableData(h,2) = distanceTableData(h,2)/Qcounter(h,2);
                    distanceTableData(h,3) = distanceTableData(h,3)/Qcounter(h,3);
                    distanceTableData(h,4) = distanceTableData(h,4)/Qcounter(h,4);
                    
                end
                set(app.UITable3,'Data',distanceTableData);
        end

        % Button pushed function: CopyButton_3
        function CopyButton_3Pushed(app, event)
            global distanceTableData
            app.num2clip(distanceTableData);            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {776, 776};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {349, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ReadCSVButton = uibutton(app.LeftPanel, 'push');
            app.ReadCSVButton.ButtonPushedFcn = createCallbackFcn(app, @ReadCSVButtonPushed, true);
            app.ReadCSVButton.Text = {'Read CSV'; ''};
            app.ExperimentsListBoxLabel = uilabel(app.LeftPanel);
            app.ExperimentsListBoxLabel.Text = 'Experiments';
            app.ExperimentsListBox = uilistbox(app.LeftPanel);
            app.ExperimentsListBox.Items = {};
            app.ExperimentsListBox.ValueChangedFcn = createCallbackFcn(app, @ExperimentsListBoxValueChanged, true);
            app.ExperimentsListBox.Value = {};
            app.FliesListBoxLabel = uilabel(app.LeftPanel);
            app.FliesListBoxLabel.Text = 'Flies';
            app.FliesListBox = uilistbox(app.LeftPanel);
            app.FliesListBox.Items = {};
            app.FliesListBox.Multiselect = 'on';
            app.FliesListBox.ValueChangedFcn = createCallbackFcn(app, @FliesListBoxValueChanged, true);
            app.FliesListBox.Value = {};
            app.ReadyLampLabel = uilabel(app.LeftPanel);
            app.ReadyLampLabel.Text = 'Ready';
            app.ReadyLamp = uilamp(app.LeftPanel);
            app.AllButton = uibutton(app.LeftPanel, 'push');
            app.AllButton.ButtonPushedFcn = createCallbackFcn(app, @AllButtonPushed, true);
            app.AllButton.Text = 'All';
            app.StartFrameEditFieldLabel = uilabel(app.LeftPanel);
            app.StartFrameEditFieldLabel.Text = 'StartFrame';
            app.StartFrameEditField = uieditfield(app.LeftPanel, 'numeric');
            app.StartFrameEditField.Value = 1;
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.ArenaPlotTab = uitab(app.TabGroup);
            app.ArenaPlotTab.Title = 'Arena Plot';
            app.UIAxes = uiaxes(app.ArenaPlotTab);
            title(app.UIAxes, 'ArenaPlot')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-15 15];
            app.UIAxes.YLim = [-15 15];
            app.UIAxes.XTick = 30;
            app.UIAxes.YTick = 30;
            app.FrameSliderLabel = uilabel(app.ArenaPlotTab);
            app.FrameSliderLabel.Text = 'Frame';
            app.FrameSlider = uislider(app.ArenaPlotTab);
            app.FrameSlider.ValueChangingFcn = createCallbackFcn(app, @FrameSliderValueChanging, true);
            app.PlayButton = uibutton(app.ArenaPlotTab, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @Play, true);
            app.PlayButton.Text = 'Play';
            app.LabelsButton = uibutton(app.ArenaPlotTab, 'push');
            app.LabelsButton.ButtonPushedFcn = createCallbackFcn(app, @LabelsButtonPushed, true);
            app.LabelsButton.Text = 'Labels';
            app.TimeTextAreaLabel = uilabel(app.ArenaPlotTab);
            app.TimeTextAreaLabel.Text = 'Time';
            app.TimeTextArea = uitextarea(app.ArenaPlotTab);
            app.FrameTextAreaLabel = uilabel(app.ArenaPlotTab);
            app.FrameTextAreaLabel.Text = 'Frame';
            app.FrameTextArea = uitextarea(app.ArenaPlotTab);
            app.PITab = uitab(app.TabGroup);
            app.PITab.Title = 'PI';
            app.PIButton = uibutton(app.PITab, 'push');
            app.PIButton.ButtonPushedFcn = createCallbackFcn(app, @PIButtonPushed, true);
            app.PIButton.Text = 'PI';
            app.PIResultsTextAreaLabel = uilabel(app.PITab);
            app.PIResultsTextAreaLabel.Text = 'PI Results';
            app.PIResultsTextArea = uitextarea(app.PITab);
            app.StartIntervalEditFieldLabel = uilabel(app.PITab);
            app.StartIntervalEditFieldLabel.Text = 'Start Interval';
            app.StartIntervalEditField = uieditfield(app.PITab, 'numeric');
            app.StartIntervalEditField.Value = 25;
            app.EndofIntervalEditFieldLabel = uilabel(app.PITab);
            app.EndofIntervalEditFieldLabel.Text = 'End of Interval';
            app.EndofIntervalEditField = uieditfield(app.PITab, 'numeric');
            app.EndofIntervalEditField.Value = 30;
            app.TimeIntervalsinthetwo30secondlightphasesLabel = uilabel(app.PITab);
            app.TimeIntervalsinthetwo30secondlightphasesLabel.Text = 'Time Intervals in the two 30 second light phases';
            app.PioverTimeButton = uibutton(app.PITab, 'push');
            app.PioverTimeButton.ButtonPushedFcn = createCallbackFcn(app, @PioverTimeButtonPushed, true);
            app.PioverTimeButton.Text = 'Pi over Time';
            app.TabGroup2 = uitabgroup(app.PITab);
            app.GraphTab = uitab(app.TabGroup2);
            app.GraphTab.Title = 'Graph';
            app.UIAxes2 = uiaxes(app.GraphTab);
            title(app.UIAxes2, 'PI')
            xlabel(app.UIAxes2, 'Time ')
            ylabel(app.UIAxes2, 'PI 2+3')
            zlabel(app.UIAxes2, 'Z')
            app.Table = uitab(app.TabGroup2);
            app.Table.Title = 'Table';
            app.UITable2 = uitable(app.Table);
            app.UITable2.ColumnName = {'Time'; 'Pi'};
            app.UITable2.RowName = {};
            app.CopyButton_2 = uibutton(app.Table, 'push');
            app.CopyButton_2.ButtonPushedFcn = createCallbackFcn(app, @CopyButton_2Pushed, true);
            app.CopyButton_2.Text = 'Copy';
            app.DistanceTab = uitab(app.TabGroup);
            app.DistanceTab.Title = 'Distance';
            app.TabGroup3 = uitabgroup(app.DistanceTab);
            app.ByIntervalTab = uitab(app.TabGroup3);
            app.ByIntervalTab.Title = 'By Interval';
            app.UITable = uitable(app.ByIntervalTab);
            app.UITable.ColumnName = {'Fly'; 'Distance'};
            app.UITable.RowName = {};
            app.CopyButton = uibutton(app.ByIntervalTab, 'push');
            app.CopyButton.ButtonPushedFcn = createCallbackFcn(app, @CopyButtonPushed, true);
            app.CopyButton.Text = 'Copy';
            app.AbsoluteTimeIntervalsofthewhole120secondVideoLabel = uilabel(app.ByIntervalTab);
            app.AbsoluteTimeIntervalsofthewhole120secondVideoLabel.Text = 'Absolute Time Intervals of the whole 120 second Video';
            app.StartDistanceIntervalEditField = uieditfield(app.ByIntervalTab, 'numeric');
            app.StartDistanceIntervalEditField.Value = 1;
            app.StartDistanceIntervalEditFieldLabel = uilabel(app.ByIntervalTab);
            app.StartDistanceIntervalEditFieldLabel.Text = 'Start Distance Interval';
            app.EndDistanceIntervalEditField = uieditfield(app.ByIntervalTab, 'numeric');
            app.EndDistanceIntervalEditField.Value = 120;
            app.EndDistanceIntervalEditFieldLabel = uilabel(app.ByIntervalTab);
            app.EndDistanceIntervalEditFieldLabel.Text = 'End Distance Interval';
            app.CalculateDistanceButton = uibutton(app.ByIntervalTab, 'push');
            app.CalculateDistanceButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateDistanceButtonPushed, true);
            app.CalculateDistanceButton.Text = 'Calculate Distance';
            app.ByQuadrantTab = uitab(app.TabGroup3);
            app.ByQuadrantTab.Title = 'By Quadrant';
            app.UITable3 = uitable(app.ByQuadrantTab);
            app.UITable3.ColumnName = {'Quadrant 1'; 'Quadrant 2'; 'Quadrant 3'; 'Quadrant 4'};
            app.UITable3.RowName = {};
            app.CalculateButton = uibutton(app.ByQuadrantTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.CopyButton_3 = uibutton(app.ByQuadrantTab, 'push');
            app.CopyButton_3.ButtonPushedFcn = createCallbackFcn(app, @CopyButton_3Pushed, true);
            app.CopyButton_3.Text = 'Copy';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_Quantification

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end