classdef GUI_preversion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        TestButton                     matlab.ui.control.Button
        StartFrameEditField            matlab.ui.control.NumericEditField
        StartFrameEditFieldLabel       matlab.ui.control.Label
        AllButton                      matlab.ui.control.Button
        ReadyLamp                      matlab.ui.control.Lamp
        ReadyLampLabel                 matlab.ui.control.Label
        FliesListBox                   matlab.ui.control.ListBox
        FliesListBoxLabel              matlab.ui.control.Label
        ExperimentsListBox             matlab.ui.control.ListBox
        ExperimentsListBoxLabel        matlab.ui.control.Label
        ReadCSVButton                  matlab.ui.control.Button
        RightPanel                     matlab.ui.container.Panel
        TabGroup                       matlab.ui.container.TabGroup
        ArenaPlotTab                   matlab.ui.container.Tab
        FrameTextArea                  matlab.ui.control.TextArea
        FrameTextAreaLabel             matlab.ui.control.Label
        TimeTextArea                   matlab.ui.control.TextArea
        TimeTextAreaLabel              matlab.ui.control.Label
        LabelsButton                   matlab.ui.control.Button
        PlayButton                     matlab.ui.control.Button
        FrameSlider                    matlab.ui.control.Slider
        FrameSliderLabel               matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        PITab                          matlab.ui.container.Tab
        StartPIIntervalEditField       matlab.ui.control.NumericEditField
        StartPIIntervalEditFieldLabel  matlab.ui.control.Label
        ResultsTextArea                matlab.ui.control.TextArea
        ResultsTextAreaLabel           matlab.ui.control.Label
        EndofPIIntervalEditField       matlab.ui.control.NumericEditField
        EndofPIIntervalEditFieldLabel  matlab.ui.control.Label
        PIButton                       matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

% This GUI was written by Hannes Euler. From this version only a specific
% part of the code was used to generate plots of the tracking data of
% different time periods. See lines 336-340 for details.
      
    methods (Access = private)
        function [xout,yout] = AllFrames(app,data)
            global xout yout
%             if ~exist('selection','var')
%                 selection = 1:length(data);
%             end
            xout = NaN([3001 length(data)]);
            yout = NaN([3001 length(data)]);
            %start = app.StartFrameEditField.Value;
            
            %start = app.StartOfProtocol(data);
            %disp(start);
            for z= 1:length(data)
                start = data(z).raw.frame(1);
                ending = data(z).raw.frame(end);
                X_array = data(z).raw.X_cm_;%(start:start+3000);
                Y_array = data(z).raw.Y_cm_;%(start:start+3000);
                %X_array = X_array-14.3;
                %Y_array = (Y_array-15.3)*-1;
        
                X_array = X_array-16.75;
                Y_array = (Y_array-11.8)*-1;
%                 X_array = X_array-16.3;
%                 Y_array = (Y_array-11.5)*-1;
        
                xout(start+1:ending+1,z) = X_array;
                yout(start+1:ending+1,z) = Y_array;


            end

            plot(app.UIAxes,xout,yout);
            hold(app.UIAxes,'on')

            xline(app.UIAxes,0);
            yline(app.UIAxes,0);

            hold(app.UIAxes,'off')
            end
        
        function fileNamePre = ExperimentNameShow(~,file)
            [~,name,~] = fileparts(file);
            startSpeciName = regexp(name,'_fly');
            %speciName = name(startSpeciName+1:end);
            fileNamePre = name(1:startSpeciName-1);
            
        end
        
        function fileNamePre = ExperimentNameHide(~,file)
            [~,name,~] = fileparts(file);
            startSpeciName = regexp(name,'_fly');
            %speciName = name(startSpeciName+1:end);
            fileNamePre = name(1:startSpeciName);
            
        end        
        
        function PlotHelper(app,path,fileNamePre)
            global X Y
            global data
            global d
            disp("Start Helper");
            d=dir(append(path,fileNamePre,'*.csv'));   % dir struct of all pertinent .csv file
            n=length(d);        % how many there were
            data = struct;
            warning('off','MATLAB:table:ModifiedAndSavedVarnames');
            for i=1:n
                data(i).raw = readtable(append(path,d(i).name));  % read each file
            end
            warning('on','MATLAB:table:ModifiedAndSavedVarnames');
            app.UIAxes.Title.String = fileNamePre;
            disp("Start AllFrames");
            [X,Y] = AllFrames(app,data);
            disp("Stop helper");
        end
        
        function start = StartOfProtocol(~,data)
            N = zeros(size(data(1).raw,1),1);
            for z = 1:size(data(1).raw,1)
                    N(z) = NIndi(data,z);
            end
            k = find(N<1,1);
            %start = k - 3000; solange Ende verbuggt


        end
        
        function results = PI(app)
            global X Y
            clear frame
            global frame
            %start = 
            %Erste Phase
            measureStartTime = app.StartPIIntervalEditField.Value;
            measureStopTime = app.EndofPIIntervalEditField.Value;
            startFrame = ((measureStartTime+30)*25);        
            stopFrame = ((measureStopTime+30)*25);          
            %interval = stopFrame-startFrame;
            z=0;
            for f = startFrame:stopFrame
                z = z+1;
            
            for x = 1:width(X)
                frameN = 0;
                if (X(f,x) < 0 && Y(f,x) < 0) || (X(f,x) > 0 && Y(f,x) > 0)
                    frameN = 1;
                end
                frame1(z,x) = frameN;
            end
            end
            %Zweite Phase
            startFrame = ((measureStartTime+90)*25);
            stopFrame = ((measureStopTime+90)*25); 
            z=0;
            for f = startFrame:stopFrame
                z = z+1;
                for x = 1:width(X)
                    frameN = 0;
                    if (X(f,x) < 0 && Y(f,x) > 0) || (X(f,x) > 0 && Y(f,x) < 0)
                        frameN = 1;
                    end
                    frame2(z,x) = frameN;
                end
            end
            frame = cat(1,frame1,frame2);
            num = sum(frame,2);
            lF = mean(num);
            dF = width(X) - lF;
            results = (lF - dF) / (lF+dF);
        end
        end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ReadCSVButton
        function ReadCSVButtonPushed(app, event)
            clear data
            clear d
            global path
            app.ReadyLamp.Color = 'red';
            
            [~,path] = uigetfile('*.csv');
            folderContent = dir(path);
            preNames = {folderContent.name};
            namesShow = cellfun(@app.ExperimentNameShow,preNames,'UniformOutput',false);
            namesHide = cellfun(@app.ExperimentNameHide,preNames,'UniformOutput',false);
            app.ExperimentsListBox.Items = unique(namesShow(3:end));
            app.ExperimentsListBox.ItemsData = unique(namesHide(3:end));
            app.ReadyLamp.Color = 'green'; 
            %app.ResultsTextArea.Value = "Test \b Bla";
            
        end

        % Value changed function: ExperimentsListBox
        function ExperimentsListBoxValueChanged(app, event)
            value = app.ExperimentsListBox.Value;
            app.ReadyLamp.Color = 'red';
            pause(0.5)
            global data  
            global path
            app.PlotHelper(path,value);
            app.FliesListBox.Items = cellstr(string(1:size(data,2)));
            app.FliesListBox.Value = app.FliesListBox.Items;
            app.ReadyLamp.Color = 'green';            
        end

        % Value changing function: FrameSlider
        function FrameSliderValueChanging(app, event)
            global X;
            global Y;
          
            
            app.UIAxes.PositionConstraint = 'innerposition';

            changingValue = event.Value;
            frames = size(X,1);
            %disp (frames);
            
            FramePos = round(frames / 100 * changingValue);
            app.TimeTextArea.Value = num2str(FramePos/25);
            app.FrameTextArea.Value = num2str(FramePos);
            valueString = app.FliesListBox.Value;
            value = cellfun(@str2num,valueString);
            
            if changingValue < 1 || FramePos > frames-25
                plot(app.UIAxes,X(:,value),Y(:,value));
                hold(app.UIAxes,'on')
                xline(app.UIAxes,0);
                yline(app.UIAxes,0);
                hold(app.UIAxes,'off')
            else
               
                plot(app.UIAxes,X(FramePos:FramePos+25,value),Y(FramePos:FramePos+25,value));
                hold(app.UIAxes,'on') 
                scatter(app.UIAxes,X(FramePos+25,value),Y(FramePos+25,value));
                
                
                %hold(app.UIAxes,'on')   
                xline(app.UIAxes,0);
                yline(app.UIAxes,0);                
                hold(app.UIAxes,'off')
            end
            hold(app.UIAxes,'on')  
            if FramePos > 750 && FramePos < 1501
                
                oR = quiver(app.UIAxes,9,9,-1.5,-1.5,'LineWidth',2,'Color','black');
                uL = quiver(app.UIAxes,-9,-9,1.5,1.5,'LineWidth',2,'Color','black');

            end
            if FramePos > 2250 && FramePos < frames-100
                oL = quiver(app.UIAxes,-9,9,1.5,-1.5,'LineWidth',2,'Color','black');
                uR = quiver(app.UIAxes,9,-9,-1.5,1.5,'LineWidth',2,'Color','black');

            end
            hold(app.UIAxes,'off')
    
        end

        % Value changed function: FliesListBox
        function FliesListBoxValueChanged(app, event)
            app.ReadyLamp.Color = 'red';
            global X Y 
            value = app.FliesListBox.Value;
            value = cellfun(@str2num,value);
            plot(app.UIAxes,X(:,value),Y(:,value));
            xline(app.UIAxes,0);
            yline(app.UIAxes,0);
            app.ReadyLamp.Color = 'green';
        end

        % Button pushed function: AllButton
        function AllButtonPushed(app, event)
            app.FliesListBox.Value = app.FliesListBox.Items;
            FliesListBoxValueChanged(app, event);
        end

        % Button pushed function: PIButton
        function PIButtonPushed(app, event)
            results = PI(app);
            app.ResultsTextArea.Value = "PI: "+results;
        end

        % Button pushed function: PlayButton
        function Play(app, event)
            Slider = app.FrameSlider.Value;
            global i
            i = 100*25;
            for i = (Slider*25):10:(100*25)
                app.FrameSlider.Value = (i/25);
                myEvent.Origin = "Test";
                myEvent.Value = (i/25);
                FrameSliderValueChanging(app,myEvent);
                pause(0.02);
            end
        end

        % Callback function
        function LabelSwitchValueChanged(app, event)
            
        end

        % Button pushed function: LabelsButton
        function LabelsButtonPushed(app, event)
            global X Y
                frames = size(X,1);
                valueString = app.FliesListBox.Value;
                value = cellfun(@str2num,valueString);
                FramePos = round(frames / 100 * app.FrameSlider.Value);
                hold(app.UIAxes,'on')   
                txt = text(app.UIAxes,X(FramePos+25,value),Y(FramePos+25,value),valueString);
                hold(app.UIAxes,'off')

        end

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)
            global X
            global Y
            valueString = app.FliesListBox.Value;
            value = cellfun(@str2num,valueString);
            % For adjusting plots change the numbers in both brackets below as
            % needed (1:750=first phase, 750:1500=second phase,
            % 1500:2250=third phase, 2250:3000=fourth phase). Then, save the
            % script and klick on Test button to generate the next plot.
            plot(app.UIAxes,X(1:750,value),Y(1:750,value));
            xline(app.UIAxes,0);
            yline(app.UIAxes,0);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {769, 769};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {364, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ReadCSVButton = uibutton(app.LeftPanel, 'push');
            app.ReadCSVButton.ButtonPushedFcn = createCallbackFcn(app, @ReadCSVButtonPushed, true);
            app.ReadCSVButton.Text = {'Read CSV'; ''};
            app.ExperimentsListBoxLabel = uilabel(app.LeftPanel);
            app.ExperimentsListBoxLabel.Text = 'Experiments';
            app.ExperimentsListBox = uilistbox(app.LeftPanel);
            app.ExperimentsListBox.Items = {};
            app.ExperimentsListBox.ValueChangedFcn = createCallbackFcn(app, @ExperimentsListBoxValueChanged, true);
            app.ExperimentsListBox.Value = {};
            app.FliesListBoxLabel = uilabel(app.LeftPanel);
            app.FliesListBoxLabel.Text = 'Flies';
            app.FliesListBox = uilistbox(app.LeftPanel);
            app.FliesListBox.Items = {};
            app.FliesListBox.Multiselect = 'on';
            app.FliesListBox.ValueChangedFcn = createCallbackFcn(app, @FliesListBoxValueChanged, true);
            app.FliesListBox.Value = {};
            app.ReadyLampLabel = uilabel(app.LeftPanel);
            app.ReadyLampLabel.Text = 'Ready';
            app.ReadyLamp = uilamp(app.LeftPanel);
            app.AllButton = uibutton(app.LeftPanel, 'push');
            app.AllButton.ButtonPushedFcn = createCallbackFcn(app, @AllButtonPushed, true);
            app.AllButton.Text = 'All';
            app.StartFrameEditFieldLabel = uilabel(app.LeftPanel);
            app.StartFrameEditFieldLabel.Text = 'StartFrame';
            app.StartFrameEditField = uieditfield(app.LeftPanel, 'numeric');
            app.StartFrameEditField.Value = 1;
            app.TestButton = uibutton(app.LeftPanel, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Text = 'Test';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.ArenaPlotTab = uitab(app.TabGroup);
            app.ArenaPlotTab.Title = 'Arena Plot';
            app.UIAxes = uiaxes(app.ArenaPlotTab);
            title(app.UIAxes, 'ArenaPlot')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-15 15];
            app.UIAxes.YLim = [-15 15];
            app.UIAxes.XTick = 30;
            app.UIAxes.YTick = 30;
            app.FrameSliderLabel = uilabel(app.ArenaPlotTab);
            app.FrameSliderLabel.Text = 'Frame';
            app.FrameSlider = uislider(app.ArenaPlotTab);
            app.FrameSlider.ValueChangingFcn = createCallbackFcn(app, @FrameSliderValueChanging, true);
            app.PlayButton = uibutton(app.ArenaPlotTab, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @Play, true);
            app.PlayButton.Text = 'Play';
            app.LabelsButton = uibutton(app.ArenaPlotTab, 'push');
            app.LabelsButton.ButtonPushedFcn = createCallbackFcn(app, @LabelsButtonPushed, true);
            app.LabelsButton.Text = 'Labels';
            app.TimeTextAreaLabel = uilabel(app.ArenaPlotTab);
            app.TimeTextAreaLabel.Text = 'Time';
            app.TimeTextArea = uitextarea(app.ArenaPlotTab);
            app.FrameTextAreaLabel = uilabel(app.ArenaPlotTab);
            app.FrameTextAreaLabel.Text = 'Frame';
            app.FrameTextArea = uitextarea(app.ArenaPlotTab);
            app.PITab = uitab(app.TabGroup);
            app.PITab.Title = 'PI';
            app.PIButton = uibutton(app.PITab, 'push');
            app.PIButton.ButtonPushedFcn = createCallbackFcn(app, @PIButtonPushed, true);
            app.PIButton.Text = 'PI';
            app.EndofPIIntervalEditFieldLabel = uilabel(app.PITab);
            app.EndofPIIntervalEditFieldLabel.Text = 'End of PI Interval';
            app.EndofPIIntervalEditField = uieditfield(app.PITab, 'numeric');
            app.EndofPIIntervalEditField.Value = 30;
            app.ResultsTextAreaLabel = uilabel(app.PITab);
            app.ResultsTextAreaLabel.Text = 'Results';
            app.ResultsTextArea = uitextarea(app.PITab);
            app.StartPIIntervalEditFieldLabel = uilabel(app.PITab);
            app.StartPIIntervalEditFieldLabel.Text = 'Start PI Interval';
            app.StartPIIntervalEditField = uieditfield(app.PITab, 'numeric');
            app.StartPIIntervalEditField.Value = 25;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_preversion

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end